classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Menu                  matlab.ui.container.Menu
        NewProjectMenu        matlab.ui.container.Menu
        OpenProjectMenu       matlab.ui.container.Menu
        ToolsMenu             matlab.ui.container.Menu
        MotionControllerMenu  matlab.ui.container.Menu
        VNAMenu               matlab.ui.container.Menu
        QuitMenu              matlab.ui.container.Menu
        ProjectnamehereLabel  matlab.ui.control.Label
        ProjectLabel          matlab.ui.control.Label
        ManageJobsButton      matlab.ui.control.Button
        CameraButton          matlab.ui.control.Button
        RunJobsButton         matlab.ui.control.Button
    end


    properties (Access = private)
        MotionControllerApp % Reference to MotionControllerApp dialogue
        ManageJobs % Reference to ManageJobs dialogue
    end

    properties (Access = public)
        MotionControlLogger % Public so other windows can access it.
        MotionController
    end

    methods (Access = private)
        function newMotionControlLogEvent(app, msg, logs)
            %
            % TODO: There is a known bug where if you open, then close,
            % then open the motion controller app, an error occurs related
            % to logging callbacks. This is supposed to solve it, but
            % doesn't. Checking isvalid() or isobject() doesn't work either.
            % And if I set app.MotionControllerApp to something like false
            % after it's deleted, I get other errors.
            % What's actually going wrong?
            %
            if ~isempty(app.MotionControllerApp)
                app.MotionControllerApp.onLogEvent(msg, logs);
            end
        end
    end

    methods (Access = public)
        function closingMotionControlApp(app)
            % called from MotionControlApp when it's being closed
            app.MotionControllerMenu.Enable = 'on';
            delete(app.MotionControllerApp);
        end

        function closingManageJobs(app)
            % called from ManageJobs dialogue when it's being closed
            app.ManageJobsButton.Enable = 'on';
            delete(app.ManageJobs);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set up logging for motion controller
            app.MotionControlLogger = Logger();
            app.MotionControlLogger.SetCallback(@(m, l) app.newMotionControlLogEvent(m, l));

            % Set up motion controller
            app.MotionController = MotionController.MI4190_Prologix("/dev/ttyUSB0", 4, [1 2 4], app.MotionControlLogger)
            %app.MotionController = MotionController.Dummy([1 2 3], app.MotionControlLogger);
        end

        % Menu selected function: MotionControllerMenu
        function MotionControllerMenuSelected(app, event)
            app.MotionControllerApp = MotionControllerApp(app);
            app.MotionControllerMenu.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close all subwindows, then the main app.
            delete(app.MotionControllerApp);
            delete(app.ManageJobs);
            delete(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            app.MotionControlLogger.Info("asdfadsf");
        end

        % Button pushed function: ManageJobsButton
        function ManageJobsButtonPushed(app, event)
            app.ManageJobs = ManageJobs(app);
            app.ManageJobsButton.Enable = 'off';
        end

        % Button pushed function: RunJobsButton
        function RunJobsButtonPushed(app, event)
            msgbox("TODO");
        end

        % Button pushed function: CameraButton
        function CameraButtonPushed(app, event)
            msgbox("TODO");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.NewProjectMenu = uimenu(app.Menu);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.Menu);
            app.OpenProjectMenu.Text = 'Open Project';
            app.ToolsMenu = uimenu(app.Menu);
            app.ToolsMenu.Text = 'Tools';
            app.MotionControllerMenu = uimenu(app.ToolsMenu);
            app.MotionControllerMenu.MenuSelectedFcn = createCallbackFcn(app, @MotionControllerMenuSelected, true);
            app.MotionControllerMenu.Tooltip = {'Motion controller utilities'};
            app.MotionControllerMenu.Text = 'Motion Controller';
            app.VNAMenu = uimenu(app.ToolsMenu);
            app.VNAMenu.Tooltip = {'VNA config and visual view'};
            app.VNAMenu.Text = 'VNA';
            app.QuitMenu = uimenu(app.Menu);
            app.QuitMenu.Text = 'Quit';
            app.RunJobsButton = uibutton(app.UIFigure, 'push');
            app.RunJobsButton.ButtonPushedFcn = createCallbackFcn(app, @RunJobsButtonPushed, true);
            app.RunJobsButton.Tooltip = {'Run measurement jobs'};
            app.RunJobsButton.Text = 'Run Jobs';
            app.CameraButton = uibutton(app.UIFigure, 'push');
            app.CameraButton.ButtonPushedFcn = createCallbackFcn(app, @CameraButtonPushed, true);
            app.CameraButton.Tooltip = {'Open the camera view'};
            app.CameraButton.Text = 'Camera';
            app.ManageJobsButton = uibutton(app.UIFigure, 'push');
            app.ManageJobsButton.ButtonPushedFcn = createCallbackFcn(app, @ManageJobsButtonPushed, true);
            app.ManageJobsButton.Tooltip = {'Configure measurement jobs'};
            app.ManageJobsButton.Text = 'Manage Jobs';
            app.ProjectLabel = uilabel(app.UIFigure);
            app.ProjectLabel.Text = 'Project:';
            app.ProjectnamehereLabel = uilabel(app.UIFigure);
            app.ProjectnamehereLabel.Text = 'Project name here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end