classdef MotionControllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LogBox                 matlab.ui.control.TextArea
        TabGroup               matlab.ui.container.TabGroup
        ManualControlTab       matlab.ui.container.Tab
        GridLayout2            matlab.ui.container.GridLayout
        STOPALLButton          matlab.ui.control.Button
        Axis3Panel             matlab.ui.container.Panel
        MotionControllerAxis3  MotionControllerAxis
        Axis2Panel             matlab.ui.container.Panel
        MotionControllerAxis2  MotionControllerAxis
        Axis1Panel             matlab.ui.container.Panel
        MotionControllerAxis1  MotionControllerAxis
        ConfigTab              matlab.ui.container.Tab
        GetErrorsButton        matlab.ui.control.Button
        GetStatusButton        matlab.ui.control.Button
        CheckButton            matlab.ui.control.Button
        ConnectedLamp          matlab.ui.control.Lamp
        ConnectedLampLabel     matlab.ui.control.Label
        SavelogButton          matlab.ui.control.Button
        ClearlogButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Reference back to the main app
    end
    
    methods (Access = private)
        function name = getAxisName(app, axis)
            name = app.CallingApp.MotionController.getName(axis);
        end

        function ec = setupStateChangeEventHandler(app)
            ec = app.CallingApp.MotionController.getEventContainer();
            addlistener(ec, "StateChange", @(src, data) app.changeEventHandler(src, data));
        end

        % Called when MotionController emits events
        function changeEventHandler(app, eventSrc, eventData)
            app.onAxisStateChange(eventData);
        end

        function updateAxisPositionDisplay(app, axis, position)
            switch axis
                case 1
                    app.MotionControllerAxis1.AxisPosition = position;
                case 2
                    app.MotionControllerAxis2.AxisPosition = position;
                case 3
                    app.MotionControllerAxis3.AxisPosition = position;
                otherwise
                    error('Unknown axis: ' + axis);
            end
        end

        function onAxisStateChange(app, state)
            % This is the onStateChange callback for MotionController.

            app.updateAxisPositionDisplay(state.Axis, state.Position);

            switch state.Axis
                case 1
                    app.MotionControllerAxis1.Moving = state.Moving;
                    app.MotionControllerAxis1.Fault = state.Fault;
                case 2
                    app.MotionControllerAxis2.Moving = state.Moving;
                    app.MotionControllerAxis2.Fault = state.Fault;
                case 3
                    app.MotionControllerAxis3.Moving = state.Moving;
                    app.MotionControllerAxis3.Fault = state.Fault;
                otherwise
                    error('Unknown axis: ' + axis);
            end
        end

        function updateConnectedLampDisplay(app, state)
            % true means connected, false means disconnected
            if state
                app.ConnectedLamp.Color = 'green';
                app.CheckButton.Enable = false;
                app.GetStatusButton.Enable = true;
                app.GetErrorsButton.Enable = true;
            else
                app.ConnectedLamp.Color = 'red';
                app.CheckButton.Enable = true;
                app.GetStatusButton.Enable = false;
                app.GetErrorsButton.Enable = false;
            end
        end

        function onConnectStateChange(app, state)
            % TODO: Probably just move updateConnectedLampDisplay() into
            % here.
            app.updateConnectedLampDisplay(state);
        end
    end
    
    methods (Access = public)
        
        function onLogEvent(app, msg, logs)
            try
                if msg == ""
                    app.LogBox.Value = logs;
                else
                    % Add an extra newline due to a bug in scroll().
                    % I left a comment about it here:
                    % https://www.mathworks.com/matlabcentral/answers/351131-how-do-i-make-a-full-text-area-scroll-up-when-i-add-more-text
                    app.LogBox.Value = strcat(logs, {newline});

                    % I tried only appending the new data,
                    % but nothing seems to make matlab happy.
                    % https://www.mathworks.com/matlabcentral/answers/747777-why-n-or-newline-doesn-t-work?s_tid=answers_rc1-2_p2_MLT
                    %app.LogBox.Value = strcat(app.LogBox.Value, {newline}, "asdf");
                    %app.LogBox.Value = strcat(app.LogBox.Value, msg, {newline});
                end

                scroll(app.LogBox, 'bottom');
            catch e
                warning("Error in MotionControllerApp::onLogEvent");
                disp(e);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.LogBox.Value = app.CallingApp.MotionControlLogger.GetLog();

            % TODO: Replace this with the newer event model
            app.CallingApp.MotionController.setOnConnectStateChangeCallback(@(s) app.onConnectStateChange(s));

            % Set up event handlers
            app.setupStateChangeEventHandler();

            % Get connection state
            app.updateConnectedLampDisplay(app.CallingApp.MotionController.isConnected());

            % Label axes based on information from controller
            app.Axis1Panel.Title = app.getAxisName(1);
            app.Axis2Panel.Title = app.getAxisName(2);
            app.Axis3Panel.Title = app.getAxisName(3);

            % Get current position data
            pos1 = app.CallingApp.MotionController.getPosition(1);
            app.MotionControllerAxis1.AxisPosition = pos1;
            pos2 = app.CallingApp.MotionController.getPosition(2);
            app.MotionControllerAxis2.AxisPosition = pos2;
            pos3 = app.CallingApp.MotionController.getPosition(3);
            app.MotionControllerAxis3.AxisPosition = pos3;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.MotionController.clearOnConnectStateChangeCallback();
            app.CallingApp.closingMotionControlApp();
            delete(app)
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.CallingApp.MotionControlLogger.Clear();
        end

        % Button pushed function: SavelogButton
        function SavelogButtonPushed(app, event)
            msgbox('TODO');
            % use app.CallingApp.Logger.GetLog()
        end

        % Button pushed function: STOPALLButton
        function STOPALLButtonPushed(app, event)
            app.CallingApp.MotionController.stopAll();
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            app.CallingApp.MotionController.check();
        end

        % Callback function
        function ButtonPushed(app, event)
            app.LogBox.Value = strcat(app.LogBox.Value, {newline}, "asdf");
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            % TODO: Right now this is just for axis 1
            % results are dumped into the log.
            app.CallingApp.MotionController.getStatus(1);
        end

        % Button pushed function: GetErrorsButton
        function GetErrorsButtonPushed(app, event)
            % results are dumped into the log.
            app.CallingApp.MotionController.getErrors();
        end

        % Callback function: MotionControllerAxis1
        function MotionControllerAxis1MoveToBtnPushed(app, event)
            pos = app.MotionControllerAxis1.TargetPosition;
            app.CallingApp.MotionController.moveAxisTo(1, pos);
        end

        % Callback function: MotionControllerAxis1
        function MotionControllerAxis1PlusFiveBtnPushed(app, event)
            app.CallingApp.MotionController.moveIncremental(1, 5);
        end

        % Callback function: MotionControllerAxis1
        function MotionControllerAxis1MinusFiveBtnPushed(app, event)
            app.CallingApp.MotionController.moveIncremental(1, -5);
        end

        % Callback function: MotionControllerAxis2
        function MotionControllerAxis2MoveToBtnPushed(app, event)
            pos = app.MotionControllerAxis2.TargetPosition;
            app.CallingApp.MotionController.moveAxisTo(2, pos);
        end

        % Callback function: MotionControllerAxis2
        function MotionControllerAxis2PlusFiveBtnPushed(app, event)
            app.CallingApp.MotionController.moveIncremental(2, 5);
        end

        % Callback function: MotionControllerAxis2
        function MotionControllerAxis2MinusFiveBtnPushed(app, event)
            app.CallingApp.MotionController.moveIncremental(2, -5);
        end

        % Callback function: MotionControllerAxis3
        function MotionControllerAxis3MoveToBtnPushed(app, event)
            pos = app.MotionControllerAxis3.TargetPosition;
            app.CallingApp.MotionController.moveAxisTo(3, pos);
        end

        % Callback function: MotionControllerAxis3
        function MotionControllerAxis3PlusFiveBtnPushed(app, event)
            app.CallingApp.MotionController.moveIncremental(3, 5);
        end

        % Callback function: MotionControllerAxis3
        function MotionControllerAxis3MinusFiveBtnPushed(app, event)
            app.CallingApp.MotionController.moveIncremental(3, -5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ManualControlTab = uitab(app.TabGroup);
            app.ManualControlTab.Title = 'Manual Control';
            app.GridLayout2 = uigridlayout(app.ManualControlTab);
            app.Axis1Panel = uipanel(app.GridLayout2);
            app.Axis1Panel.Title = 'Axis 1';
            app.MotionControllerAxis1 = MotionControllerAxis(app.Axis1Panel);
            app.MotionControllerAxis1.MoveToBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxis1MoveToBtnPushed, true);
            app.MotionControllerAxis1.PlusFiveBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxis1PlusFiveBtnPushed, true);
            app.MotionControllerAxis1.MinusFiveBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxis1MinusFiveBtnPushed, true);
            app.Axis2Panel = uipanel(app.GridLayout2);
            app.Axis2Panel.Title = 'Axis 2';
            app.MotionControllerAxis2 = MotionControllerAxis(app.Axis2Panel);
            app.MotionControllerAxis2.MoveToBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxis2MoveToBtnPushed, true);
            app.MotionControllerAxis2.PlusFiveBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxis2PlusFiveBtnPushed, true);
            app.MotionControllerAxis2.MinusFiveBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxis2MinusFiveBtnPushed, true);
            app.Axis3Panel = uipanel(app.GridLayout2);
            app.Axis3Panel.Title = 'Axis 3';
            app.MotionControllerAxis3 = MotionControllerAxis(app.Axis3Panel);
            app.MotionControllerAxis3.MoveToBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxis3MoveToBtnPushed, true);
            app.MotionControllerAxis3.PlusFiveBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxis3PlusFiveBtnPushed, true);
            app.MotionControllerAxis3.MinusFiveBtnPushedFcn = createCallbackFcn(app, @MotionControllerAxis3MinusFiveBtnPushed, true);
            app.STOPALLButton = uibutton(app.GridLayout2, 'push');
            app.STOPALLButton.ButtonPushedFcn = createCallbackFcn(app, @STOPALLButtonPushed, true);
            app.STOPALLButton.Text = 'STOP ALL';
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.ClearlogButton = uibutton(app.ConfigTab, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.SavelogButton = uibutton(app.ConfigTab, 'push');
            app.SavelogButton.ButtonPushedFcn = createCallbackFcn(app, @SavelogButtonPushed, true);
            app.SavelogButton.Text = 'Save log...';
            app.ConnectedLampLabel = uilabel(app.ConfigTab);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.ConfigTab);
            app.CheckButton = uibutton(app.ConfigTab, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Enable = 'off';
            app.CheckButton.Text = 'Check';
            app.GetStatusButton = uibutton(app.ConfigTab, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            app.GetErrorsButton = uibutton(app.ConfigTab, 'push');
            app.GetErrorsButton.ButtonPushedFcn = createCallbackFcn(app, @GetErrorsButtonPushed, true);
            app.GetErrorsButton.Text = 'Get Errors';
            app.LogBox = uitextarea(app.GridLayout);
            app.LogBox.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionControllerApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end