classdef RunJobs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        StopButton           matlab.ui.control.Button
        StartButton          matlab.ui.control.Button
        RunningLamp          matlab.ui.control.Lamp
        RunningLampLabel     matlab.ui.control.Label
        Axis3Label           matlab.ui.control.Label
        Axis2Label           matlab.ui.control.Label
        Axis1Label           matlab.ui.control.Label
        ProgressLabel        matlab.ui.control.Label
        Gauge_4              matlab.ui.control.LinearGauge
        Gauge_3              matlab.ui.control.LinearGauge
        Gauge_2              matlab.ui.control.LinearGauge
        Gauge                matlab.ui.control.LinearGauge
        GridLayout2          matlab.ui.container.GridLayout
        QueueJobButton       matlab.ui.control.Button
        PrepareJobNowButton  matlab.ui.control.Button
        JobsListBox          matlab.ui.control.ListBox
        JobsLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Reference back to the main app
        plan % The measurement plan to run. Populated when job is selected.
    end
    
    methods (Access = private)
        
        function refreshJobNamesList(app)
            names = app.CallingApp.getJobNames();
            app.JobsListBox.Items = names;
        end
        
        function results = runPlan(app)
            jobLog = Logger(); % TODO: Deal with the log
            runJob(app.plan, app.CallingApp.MotionController, app.CallingApp.VNA, jobLog);
            % TODO: Need to pass axes into runJob()
            % TODO: Deal with being able to stop the job
            % TODO: Record/display the results (Show progress?)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            app.Panel.Visible = false;
            app.refreshJobNamesList();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.closingRunJobs();
            delete(app)
        end

        % Button pushed function: PrepareJobNowButton
        function PrepareJobNowButtonPushed(app, event)
            app.Panel.Visible = true;

            jobName = {app.JobsListBox.Value};
            j = app.CallingApp.getJob(jobName);

            app.Panel.Title = j.name{1};
            app.plan = planJobRun(j);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.runPlan();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.JobsLabel = uilabel(app.GridLayout2);
            app.JobsLabel.Text = 'Jobs';
            app.JobsListBox = uilistbox(app.GridLayout2);
            app.PrepareJobNowButton = uibutton(app.GridLayout2, 'push');
            app.PrepareJobNowButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareJobNowButtonPushed, true);
            app.PrepareJobNowButton.Text = 'Prepare Job Now';
            app.QueueJobButton = uibutton(app.GridLayout2, 'push');
            app.QueueJobButton.Enable = 'off';
            app.QueueJobButton.Text = 'Queue Job';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.Gauge = uigauge(app.GridLayout3, 'linear');
            app.Gauge_2 = uigauge(app.GridLayout3, 'linear');
            app.Gauge_3 = uigauge(app.GridLayout3, 'linear');
            app.Gauge_4 = uigauge(app.GridLayout3, 'linear');
            app.ProgressLabel = uilabel(app.GridLayout3);
            app.ProgressLabel.Text = 'Progress';
            app.Axis1Label = uilabel(app.GridLayout3);
            app.Axis1Label.Text = 'Axis 1';
            app.Axis2Label = uilabel(app.GridLayout3);
            app.Axis2Label.Text = 'Axis 2';
            app.Axis3Label = uilabel(app.GridLayout3);
            app.Axis3Label.Text = 'Axis 3';
            app.RunningLampLabel = uilabel(app.GridLayout3);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.GridLayout3);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunJobs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end