classdef VNAApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout2          matlab.ui.container.GridLayout
        ControlsgohereLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Reference back to the main app
        el         % Event listener reference
    end
    
    methods (Access = private)
        function ec = setupStateChangeEventHandler(app)
            ec = app.CallingApp.VNA.getEventContainer();
            app.el = addlistener(ec, "Measurement", @(src, data) app.measurementHandler(src, data));
        end

        % Called when VNA emits events
        function measurementHandler(app, eventSrc, eventData)
            % Note that the results are in the format from
            % HP8720::measure(), not the final format that is exported when
            % the measurements are all completed.

            results = eventData.Results;

            % results has:
            % startFreq
            % stopFreq
            % SCAL
            % REFP
            % REFV
            % freq
            % S21

            plot(app.UIAxes, results.freq, 20*log10(abs(results.S21)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            % Set up event handlers
            app.setupStateChangeEventHandler();

            % Plot some dummy data so I remember how to do this
            x = 1:5*pi;
            y = sin(x);
            plot(app.UIAxes, x, y);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.el);
            app.CallingApp.closingVNAApp();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'VNA: S21')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'S21 (dB)')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ControlsgohereLabel = uilabel(app.GridLayout2);
            app.ControlsgohereLabel.Text = 'Controls go here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VNAApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end