classdef ViewResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        ImportFileButton           matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        GainvsFrequencyTab         matlab.ui.container.Tab
        NormalizeCheckBox          matlab.ui.control.CheckBox
        AzimuthAngleDropDown       matlab.ui.control.DropDown
        AzimuthAngleDropDownLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        GainvsAngleTab             matlab.ui.container.Tab
        NormalizeCheckBox_2        matlab.ui.control.CheckBox
        FrequencyGHzDropDown       matlab.ui.control.DropDown
        FrequencyGHzDropDownLabel  matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        AxialRatioTab              matlab.ui.container.Tab
        UIAxes_3                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Results % Results with capital R refers to variable in which file is loaded to, 
                % results with lowercase r refers to contents within the actual file
        inputazmth
        inputfreq
        az_db
        norm_az_db
        az_mag
        az_measuredResults
        fr_measuredResults
        fr_db
        fr_mag
        norm_fr_db
        Axial_Results
        tabname
        ErrLog %string containing the error from error_handle()
        temp_results %solution to error handle issue?
    end
    
    
    methods (Access = private) 
        function [measurement] = extractMeasurement1D(~, results, freq)
            % EXTRACTMEASUREMENT1D extracts simple 1D measurement results for
            % analysis.

            f = find([results.data.freq] == freq);
            if isempty(f)
                error("No match in results for specified frequency (%d)", freq);
            end

            steps = results.data(f).steps;
            if length(steps(1).pos) > 1
                error("Expected only one dimension in results");
            end

            % Frequency selected by the user
            measurement.freq = freq;

            % Intermediate stuff
            actualAxesCt = length(results.data(1).steps(1).actualPos);
            stepCt = length(steps);
            actualPositions = [steps.actualPos];

            % Specific data points
            measurement.axisNames = results.meta.axisNames;
            measurement.position = [steps.pos];
            measurement.actualPosition = reshape(actualPositions, [actualAxesCt, stepCt])';
            measurement.S21 = [steps.S21];
        end

        function [measurement] = extractMeasurement1DFreq(~, results, pos)
            % EXTRACTMEASUREMENT1D extracts all frequency data from the provided
            % one-dimensional results at a specified position.

            if ~ any([results.data(1).steps.pos] == pos)
                error("No match in results for specified position (%d)", pos);
            end

            if length(results.data(1).steps(1).pos) > 1
                error("Expected only one dimension in results");
            end

            % Position selected by the user
            measurement.position = pos;

            % Specific data points
            measurement.freqs = [results.data.freq];

            % For each frequency...
            for f = 1:length(results.data)
                steps = results.data(f).steps;
                p = find([steps.pos] == pos);
                if isempty(p) % Just in case...
                    error("No patch in results for specified position (%d)", pos);
                end

                measurement.S21(f) = steps(p).S21;
                measurement.actualPosition(f, :) = steps(p).actualPos;
            end

            measurement.axisNames = results.meta.axisNames;
        end

        function plot_az(app)
            
            if isempty(app.Results) == 1
                uiwait(msgbox("Import file before continuing.","Error","modal")); %uiwait waits until okay is clicked...supposedly
                app.NormalizeCheckBox.Value = 1;
            else
                cb = app.NormalizeCheckBox.Value;
                dd = erase(app.AzimuthAngleDropDown.Value,"°"); %removes degree symbol from dropdown "Value" and sets to dd

                app.inputazmth = str2double(dd); %dd needs to be converted to double
                load(app.Results)
                app.az_measuredResults = extractMeasurement1DFreq(app,results,app.inputazmth);

                app.az_mag = abs(app.az_measuredResults.S21);
                app.az_db = 20*log10(app.az_mag);
                max_db = max(app.az_db);
                app.norm_az_db = app.az_db - max_db;

                app.UIAxes.XTick = (1e9:2e8:3e9);
                app.UIAxes.XTickLabel = (1:0.2:3);

                    if cb == 1
                        ylabel(app.UIAxes,'Normalized Gain (dB)','FontWeight','bold');
                        plot(app.UIAxes,app.az_measuredResults.freqs,app.norm_az_db)
                    else
                        ylabel(app.UIAxes,'Magnitude (dB)','FontWeight','bold');
                        plot(app.UIAxes,app.az_measuredResults.freqs,app.az_db)
                    end
            end

        end

        function plot_fr(app)
            if isempty(app.Results) == 1
                uiwait(msgbox("Import file before continuing.","Error","modal"));
                app.NormalizeCheckBox_2.Value = 1;
            else
                cb = app.NormalizeCheckBox_2.Value;
                dd = app.FrequencyGHzDropDown.Value;

                app.inputfreq = str2double(dd) * 10^9; %converts to double and multiplies value by 10^9
                load(app.Results)
                app.fr_measuredResults = extractMeasurement1D(app,results,app.inputfreq);

                app.fr_mag = abs(app.fr_measuredResults.S21);
                app.fr_db = 20*log10(app.fr_mag);
                max_db = max(app.fr_db);
                app.norm_fr_db = app.fr_db - max_db;

                    if cb == 1
                        ylabel(app.UIAxes_2,'Normalized Gain (dB)','FontWeight','bold');
                        plot(app.UIAxes_2,app.fr_measuredResults.position,app.norm_fr_db)
                    else
                        ylabel(app.UIAxes_2,'Magnitude (dB)','FontWeight','bold');
                        plot(app.UIAxes_2,app.fr_measuredResults.position,app.fr_db)
                    end
            end

        end

        function plot_axial(app)
            if isempty(app.Axial_Results) == 1
                msgbox("Import file before continuing.","Error","modal");   %placeholder error line in case UI element is added later on 
                                                                            % that calls this function before file is uploaded
            else
                load(app.Axial_Results);
                [m1] = extractMeasurement1DFreq(app,results,0);
                ar = size(m1.freqs);
                for i = 1:numel(m1.freqs)
                    [m2] = extractMeasurement1D(app,results,m1.freqs(i));
                    s21 = 20*log10(abs(m2.S21));
                    ar(i) = max(s21) - min(s21);
                end
                freq1 = m1.freqs;
                plot(app.UIAxes_3,freq1/1e9,ar,'linewidth',1);
            end

        end

        function error_handle(app)
            switch app.tabname %logical OR not possible with switch/case statements :(
                case app.TabGroup.Children(1) %Tab 1
                    app.Results = uigetfile('*.mat'); %user selects file to load, only shows .mat files
                    if app.Results == 0 %if empty -> error
                        app.ErrLog = "No file selected.";
                    else
                        [~,~,ext] = fileparts(app.Results); %gets extension of imported file
                        if ~isequal(ext,'.mat') %if file ext. is not .mat -> error
                            app.ErrLog = "Please select a .mat file";
                        else
                            load(app.Results)
                            if length(results.data(1).steps) == 36
                                app.ErrLog = "Please upload a file that corresponds to the selected tab.";
                                %reset(app.Results) %clear(app.Results) and app.Results = [] dont work. 
                                % delete(app.Results) deletes the actual file in file explorer 
                            else
                                clear app.ErrLog
                                return
                            end
                        end
                    end
                    
                case app.TabGroup.Children(2) %Tab 2
                    app.Results = uigetfile('*.mat'); %user selects file to load, only shows .mat files
                    if app.Results == 0 %if empty -> error
                        app.ErrLog = "No file selected.";
                    else
                        [~,~,ext] = fileparts(app.Results); %gets extension of imported file
                        if ~isequal(ext,'.mat') %if file ext. is not .mat -> error
                            app.ErrLog = "Please select a .mat file";
                        else
                            load(app.Results)
                            if length(results.data(1).steps) == 36
                                app.ErrLog = "Please upload a file that corresponds to the selected tab.";
                                %reset(app.Results) %clear(app.Results) and app.Results = [] dont work. 
                                % delete(app.Results) deletes the actual file in file explorer 
                            else
                                clear app.ErrLog
                                return
                            end
                        end
                    end
                    
                case app.TabGroup.Children(3) %Tab 3
                    app.Axial_Results = uigetfile('*.mat'); %user selects file to load, only shows .mat files
                    if app.Axial_Results == 0 %if empty -> error
                        app.ErrLog = "No file selected.";
                    else
                        [~,~,ext] = fileparts(app.Axial_Results); %gets extension of imported file
                        if ~isequal(ext,'.mat') %if file ext. is not .mat -> error
                            app.ErrLog = "Please select a .mat file";
                        else
                            load(app.Axial_Results)
                            if length(results.data(1).steps) == 36
                                clear app.ErrLog
                                plot_axial(app);
                                return  
                            else
                                app.ErrLog = "Please upload a file that corresponds to the selected tab.";
                                %reset(app.Results) %clear(app.Results) and app.Results = [] dont work. 
                                % delete(app.Results) deletes the actual file in file explorer
                            end
                        end
                    end
                    
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NormalizeCheckBox.Value = 1;
            app.NormalizeCheckBox_2.Value = 1;

            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
%             app.UIAxes.XTick = (1e9:2e8:3e9);
%             app.UIAxes.XTickLabel = (1:0.2:3);

            title(app.UIAxes,''); %title not needed
            xlabel(app.UIAxes,'Frequency (GHz)','FontWeight','bold','FontSize',12);
            ylabel(app.UIAxes,'Gain (dB)','FontWeight','bold','FontSize',12);
            box(app.UIAxes, 'on');

            app.UIAxes_2.XGrid = 'on';
            app.UIAxes_2.YGrid = 'on';

            title(app.UIAxes_2,'Gain Vs Angle','FontWeight','bold','FontSize',12);
            xlabel(app.UIAxes_2,'Azimuth Angle (Degrees)','FontWeight','bold','FontSize',12);
            ylabel(app.UIAxes_2,'Gain (dB)','FontWeight','bold','FontSize',12);
            box(app.UIAxes_2, 'on');

            app.UIAxes_3.XGrid = 'on';
            app.UIAxes_3.YGrid = 'on';

            title(app.UIAxes_3, '');
            xlabel(app.UIAxes_3,'Frequency (GHz)','FontWeight','bold','FontSize',12);
            ylabel(app.UIAxes_3,'Axial Ratio (dB)','FontWeight','bold','FontSize',12);
            box(app.UIAxes_3, 'on');


        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            app.tabname = app.TabGroup.SelectedTab;
            %app.Results = uigetfile('*.mat'); %user selects file to load, only shows .mat files
            error_handle(app);
                if ~isempty(app.ErrLog) %if ErrLog contains a strings, display it, else plot results
                    uiwait(msgbox(char(app.ErrLog), "Error", "modal")); 
                else
                    load(app.Results);

                    %dropdown code for azimuth angle
                    angle_str = string([results.data(1).steps.pos]); %converts pos values to string values
                    az_angle_final = strcat(angle_str, '°'); %concatenates angle_str with degree symbol
                    app.AzimuthAngleDropDown.Items = {az_angle_final{:}}; %sets items of dropdown menu to az_angle_final
                    %unsure how to fix the warning without crashing ^
                    app.AzimuthAngleDropDown.Value = az_angle_final{ceil(length(az_angle_final)/2)}; %sets intial drop down menu value to middle value

                    %dropdown code for frequency
                    frq_str = string([results.data.freq] ./ (10^9)); %divides all frequencies by 10^9 and converts frequency values to strings
                    app.FrequencyGHzDropDown.Items = {frq_str{:}}; %sets frequency strings to items in drop down menu
                    app.FrequencyGHzDropDown.Value = frq_str{1}; %sets initial frq drop down value to first frequency

                    plot_az(app);
                    plot_fr(app);
                    

                end
        end

        % Value changed function: AzimuthAngleDropDown
        function AzimuthAngleDropDownValueChanged(app, event)
            plot_az(app);
        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxValueChanged(app, event)
            plot_az(app);
        end

        % Value changed function: FrequencyGHzDropDown
        function FrequencyGHzDropDownValueChanged(app, event)
            plot_fr(app);
        end

        % Value changed function: NormalizeCheckBox_2
        function NormalizeCheckBox_2ValueChanged(app, event)
            plot_fr(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GainvsFrequencyTab = uitab(app.TabGroup);
            app.GainvsFrequencyTab.Title = 'Gain vs. Frequency';
            app.UIAxes = uiaxes(app.GainvsFrequencyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AzimuthAngleDropDownLabel = uilabel(app.GainvsFrequencyTab);
            app.AzimuthAngleDropDownLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleDropDown = uidropdown(app.GainvsFrequencyTab);
            app.AzimuthAngleDropDown.Items = {'-30', '-20', '-10', '0', '10', '20', '30'};
            app.AzimuthAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @AzimuthAngleDropDownValueChanged, true);
            app.AzimuthAngleDropDown.Value = '0';
            app.NormalizeCheckBox = uicheckbox(app.GainvsFrequencyTab);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxValueChanged, true);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.GainvsAngleTab = uitab(app.TabGroup);
            app.GainvsAngleTab.Title = 'Gain vs. Angle';
            app.UIAxes_2 = uiaxes(app.GainvsAngleTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.FrequencyGHzDropDownLabel = uilabel(app.GainvsAngleTab);
            app.FrequencyGHzDropDownLabel.Text = 'Frequency (GHz)';
            app.FrequencyGHzDropDown = uidropdown(app.GainvsAngleTab);
            app.FrequencyGHzDropDown.Items = {};
            app.FrequencyGHzDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyGHzDropDownValueChanged, true);
            app.FrequencyGHzDropDown.Value = {};
            app.NormalizeCheckBox_2 = uicheckbox(app.GainvsAngleTab);
            app.NormalizeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBox_2ValueChanged, true);
            app.NormalizeCheckBox_2.Text = 'Normalize';
            app.AxialRatioTab = uitab(app.TabGroup);
            app.AxialRatioTab.Title = 'Axial Ratio';
            app.UIAxes_3 = uiaxes(app.AxialRatioTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ImportFileButton = uibutton(app.UIFigure, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'csunlogo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewResults

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end